classdef AnalysisPipelineBuilder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AnalysisPipelineBuilderUIFigure  matlab.ui.Figure
        WorkingDataGroupPanel           matlab.ui.container.Panel
        CellsListBoxLabel               matlab.ui.control.Label
        CellsListBox_W                  matlab.ui.control.ListBox
        SymphonydatasetsListBoxLabel    matlab.ui.control.Label
        SymphonydatasetsListBox_W       matlab.ui.control.ListBox
        OtherdataListBoxLabel           matlab.ui.control.Label
        OtherdataListBox_W              matlab.ui.control.ListBox
        AnimalsListBoxLabel             matlab.ui.control.Label
        AnimalsListBox_W                matlab.ui.control.ListBox
        TotalsPanel_2                   matlab.ui.container.Panel
        animalsEditField_2Label         matlab.ui.control.Label
        animalsEditField_W              matlab.ui.control.EditField
        cellsEditField_3Label           matlab.ui.control.Label
        cellsEditField_W                matlab.ui.control.EditField
        epochsEditField_4Label          matlab.ui.control.Label
        epochsEditField_W               matlab.ui.control.EditField
        eyesEditField_2Label            matlab.ui.control.Label
        eyesEditField_W                 matlab.ui.control.EditField
        datasetsEditField_4Label        matlab.ui.control.Label
        datasetsEditField_W             matlab.ui.control.EditField
        otherfilesEditField_4Label      matlab.ui.control.Label
        otherfilesEditField_W           matlab.ui.control.EditField
        SaveDataGroupButton             matlab.ui.control.Button
        ClearWorkingGroupButton         matlab.ui.control.Button
        DitchanimalButton               matlab.ui.control.Button
        DitchcellButton                 matlab.ui.control.Button
        DitchdatasetButton              matlab.ui.control.Button
        DitchotherdataButton            matlab.ui.control.Button
        BuildanalysisPanel              matlab.ui.container.Panel
        Label                           matlab.ui.control.Label
        FuncTypeDropDown_1              matlab.ui.control.DropDown
        FuncNameDropDown_1              matlab.ui.control.DropDown
        FunctiontypeLabel               matlab.ui.control.Label
        FunctionnameLabel               matlab.ui.control.Label
        StepsSpinnerLabel               matlab.ui.control.Label
        StepsSpinner                    matlab.ui.control.Spinner
        testButton                      matlab.ui.control.Button
        writetodatabaseButton_1         matlab.ui.control.Button
        exporttoh5Button_1              matlab.ui.control.Button
        CheckBox                        matlab.ui.control.CheckBox
        saveButton                      matlab.ui.control.Button
        editButton                      matlab.ui.control.Button
        ParametersetLabel               matlab.ui.control.Label
        ParamSetDropDown_1              matlab.ui.control.DropDown
        RunstepLabel                    matlab.ui.control.Label
        overwriteLabel                  matlab.ui.control.Label
        PipelinePanel                   matlab.ui.container.Panel
        LoadpipelineButton              matlab.ui.control.Button
        SavepipelineButton              matlab.ui.control.Button
        RunButton                       matlab.ui.control.Button
        h5exporttoolButton              matlab.ui.control.Button
        LoaddataPanel_2                 matlab.ui.container.Panel
        CellsListBox_2Label             matlab.ui.control.Label
        CellsListBox_L                  matlab.ui.control.ListBox
        SymphonydatasetsListBox_2Label  matlab.ui.control.Label
        SymphonydatasetsListBox_L       matlab.ui.control.ListBox
        OtherdataListBox_2Label         matlab.ui.control.Label
        OtherdataListBox_L              matlab.ui.control.ListBox
        AnimalsListBox_2Label           matlab.ui.control.Label
        AnimalsListBox_L                matlab.ui.control.ListBox
        TotalsPanel_3                   matlab.ui.container.Panel
        animalsEditField_2Label_2       matlab.ui.control.Label
        animalsEditField_L              matlab.ui.control.EditField
        cellsEditField_3Label_2         matlab.ui.control.Label
        cellsEditField_L                matlab.ui.control.EditField
        epochsEditField_4Label_2        matlab.ui.control.Label
        epochsEditField_L               matlab.ui.control.EditField
        eyesEditField_2Label_2          matlab.ui.control.Label
        eyesEditField_L                 matlab.ui.control.EditField
        datasetsEditField_4Label_2      matlab.ui.control.Label
        datasetsEditField_L             matlab.ui.control.EditField
        otherfilesEditField_4Label_2    matlab.ui.control.Label
        otherfilesEditField_L           matlab.ui.control.EditField
        AlltoWGButton                   matlab.ui.control.Button
        ClearLoadedButton               matlab.ui.control.Button
        LoadqueryButton                 matlab.ui.control.Button
        QueryeditorButton               matlab.ui.control.Button
        LoadDataGroupButton             matlab.ui.control.Button
        LoadcellnamesButton             matlab.ui.control.Button
        LoadsinglecellButton            matlab.ui.control.Button
        cell_idEditField                matlab.ui.control.EditField
        AttachotherdataButton_2         matlab.ui.control.Button
        FilterbyepochfunctionButton     matlab.ui.control.Button
        AnimaltoWGButton                matlab.ui.control.Button
        CelltoWGButton                  matlab.ui.control.Button
        DatasettoWGButton               matlab.ui.control.Button
        OtherfilestoWGButton            matlab.ui.control.Button
        MergecompatibleLampLabel        matlab.ui.control.Label
        MergecompatibleLamp             matlab.ui.control.Lamp
    end

    
    properties (Access = private)
        loadedQuery = []; % current query result for the "loaded" panel
        loadedQueryToDisplay = []; % loaded query further sub-divided by what is clicked in the list box
        workingQuery = []; % same as above but for working panel
        workingQueryToDisplay = []; % same as above but for working panel
        searchTable = []; % joined table on which to run queries
        analysisParams = {}; % param struct for each analysis step (or empty)
    end
    
    properties (Access = public)
        analysisOutput = []; % struct from last run analysis, including entities and results
    end
    
    methods (Access = private)
        
        function updateLoadedListBoxes(app,caller)
            if isempty(app.loadedQueryToDisplay) || app.loadedQueryToDisplay.count==0
                set(app.AnimalsListBox_L,'items',{});
                set(app.CellsListBox_L,'items',{});
                set(app.SymphonydatasetsListBox_L,'items',{});
                set(app.OtherdataListBox_L,'items',{});
                
                set(app.animalsEditField_L,'value',num2str(0));
                set(app.cellsEditField_L,'value',num2str(0));
                set(app.datasetsEditField_L,'value',num2str(0));
                set(app.eyesEditField_L, 'value', num2str(0));
                set(app.epochsEditField_L, 'value', num2str(0));
            else      
                if isempty(caller)
                    animals = sl.Animal & app.loadedQueryToDisplay.proj;
                    set(app.AnimalsListBox_L,'items',string(num2str(fetchn(animals,'animal_id'))))
                end
                
                if isempty(caller) || strcmp(caller, 'animals')
                    cells = sl.SymphonyRecordedCell & app.loadedQueryToDisplay.proj;
                    set(app.CellsListBox_L,'items',fetchn(cells,'cell_id'));
                end
                
                datasets = sl.Dataset & app.loadedQueryToDisplay.proj;
                set(app.SymphonydatasetsListBox_L,'items',fetchn(datasets,'dataset_name'));  
                
                eyes = sl.Eye & app.loadedQueryToDisplay.proj; %TODO not quite right, includes non-recorded eyes

                %epochs = sl.Epoch & app.loadedQueryToDisplay.proj;
                 
                %set counts only for empty caller
                if isempty(caller)
                    set(app.animalsEditField_L,'value',num2str(animals.count));
                    set(app.cellsEditField_L,'value',num2str(cells.count));
                    set(app.datasetsEditField_L,'value',num2str(datasets.count));
                    set(app.eyesEditField_L, 'value', num2str(eyes.count));
                    ep_count = app.getEpochCountInQuery(app.loadedQuery);
                    set(app.epochsEditField_L, 'value', num2str(ep_count));
                end
            end
        end
        
        function updateWorkingListBoxes(app,caller)
            if isempty(app.workingQueryToDisplay) || app.workingQueryToDisplay.count==0
                set(app.AnimalsListBox_W,'items',{});
                set(app.CellsListBox_W,'items',{});
                set(app.SymphonydatasetsListBox_W,'items',{});
                set(app.OtherdataListBox_W,'items',{});
                
                set(app.animalsEditField_W,'value',num2str(0));
                set(app.cellsEditField_W,'value',num2str(0));
                set(app.datasetsEditField_W,'value',num2str(0));
                set(app.eyesEditField_W, 'value', num2str(0));
                set(app.epochsEditField_W, 'value', num2str(0));
            else     
                if isempty(caller)
                    animals = sl.Animal & app.workingQueryToDisplay.proj;
                    set(app.AnimalsListBox_W,'items',string(num2str(fetchn(animals,'animal_id'))))
                end
                
                if isempty(caller) || strcmp(caller, 'animals')
                    cells = sl.SymphonyRecordedCell & app.workingQueryToDisplay.proj;
                    set(app.CellsListBox_W,'items',fetchn(cells,'cell_id'));
                end
               
                datasets = sl.Dataset & app.workingQueryToDisplay.proj;               
                set(app.SymphonydatasetsListBox_W,'items',fetchn(datasets,'dataset_name'));
                
                eyes = sl.Eye & app.workingQueryToDisplay.proj; %TODO not quite right, includes non-recorded eyes

                %epochs = sl.Epoch & app.workingQueryToDisplay.proj;
                
                if isempty(caller) || contains(caller,'_set')
                    animals = sl.Animal & app.workingQuery.proj;
                    cells = sl.SymphonyRecordedCell & app.workingQuery.proj;

                    set(app.animalsEditField_W,'value',num2str(animals.count));
                    set(app.cellsEditField_W,'value',num2str(cells.count));
                    set(app.datasetsEditField_W,'value',num2str(datasets.count));
                    set(app.eyesEditField_W, 'value', num2str(eyes.count));
                    ep_count = app.getEpochCountInQuery(app.workingQuery);
                    set(app.epochsEditField_W, 'value', num2str(ep_count));
                end
            end
        end
        
        function result = checkMergeCompatibility(app)
            if isempty(app.searchTable)
                result = false;
                set(app.MergecompatibleLamp,'color','white');
            elseif isempty(app.workingQuery) || isequal(app.workingQuery.header, app.searchTable.header)
                result = true;
                set(app.MergecompatibleLamp,'color','green');
            else
                result = false;
                set(app.MergecompatibleLamp,'color','red');
            end
        end
        
        function addToWorkingGroup(app, queryToAdd)
            if app.checkMergeCompatibility()
                if isempty(app.workingQuery)
                    app.workingQuery = queryToAdd;
                else
                    primaryKeyStruct_W = app.workingQuery.fetch();
                    primaryKeyStruct_L = queryToAdd.fetch();
                    mergedStruct = [primaryKeyStruct_W; primaryKeyStruct_L];
                    app.workingQuery = app.searchTable & mergedStruct;
                    disp('Merge success!');
                end
                app.workingQueryToDisplay = app.workingQuery;
                app.updateWorkingListBoxes([]);
                app.checkMergeCompatibility()
            else
                disp('Cannot merge incompatible DataGroups.');
            end
            
        end
        
        function [ep_count, ep_in_datasets] = getEpochCountInQuery(app, query)
            ep_q = sl.Epoch & query;
            dataset_structs = query.fetch('epoch_ids');
            all_ep = [];
            for i=1:length(dataset_structs)
                all_ep = [all_ep, struct('epoch_number',num2cell(dataset_structs(i).epoch_ids))];
            end
            ep_in_datasets = ep_q & all_ep;
            ep_count = ep_in_datasets.count;
        end
        
        function updateFunctionsMenu(app, event)
            caller = event.Source.Tag;
            [~, caller_num] = strtok(caller, '_');
            func_menu_name = ['FuncNameDropDown' caller_num];
            
            switch event.Value
                case 'Epoch'
                    d = dir([getenv('user_epoch_functions_folder') '*.m']);
                case 'Dataset'
                    d = dir([getenv('user_dataset_functions_folder') '*.m']);
                case 'Cell'
                    d = dir([getenv('user_cell_functions_folder') '*.m']);
                case 'Multi-cell'
                    d = dir([getenv('user_multicell_functions_folder') '*.m']);                    
            end
            N = length(d);
            fnames = cell(1,N);
            for i=1:N
                fnames{i} = strtok(d(i).name,'.');
            end
            set(app.(func_menu_name), 'items', fnames);            
        end
        
        function runAnalysisFunction(app, event)
            caller = event.Source.Tag;
            [~, caller_num] = strtok(caller, '_');
            func_menu_name = ['FuncNameDropDown' caller_num];
            functype_menu_name = ['FuncTypeDropDown' caller_num];
            
            funcName = get(app.(func_menu_name), 'value');
            funcType = get(app.(functype_menu_name), 'value');
            switch funcType
                case 'Epoch'
                    [ep_count, ep_in_datasets] = app.getEpochCountInQuery(app.workingQuery);
                    all_ep_struct = ep_in_datasets.fetch();
                    fprintf('Running %s on %d epochs...\n', funcName, ep_count);
                    %TODO - params
                    for i=1:ep_count
                        ep = sl.Epoch & all_ep_struct(i);
                        eval(sprintf('R=%s(ep,[]);', funcName));
                        app.analysisOutput(i).input = all_ep_struct(i);
                        app.analysisOutput(i).result = R;
                    end
                    fprintf('done\n');
                case 'Dataset'
                    
                case 'Cell'
                    
                case 'Mulit-cell'
                    
            end
            assignin('base','analysisOutput',app.analysisOutput);            
        end
        
        function updateParamsMenu(app, event)
            caller = event.Source.Tag;
            [~, caller_num] = strtok(caller, '_');
            func_menu_name = ['FuncNameDropDown' caller_num];
            param_menu_name = ['ParamSetDropDown' caller_num];
            functype_menu_name = ['FuncTypeDropDown' caller_num];

            funcName = get(app.(func_menu_name), 'value');
            funcType = get(app.(functype_menu_name), 'value');
            
            switch funcType
                case 'Epoch'
                    param_folder = [getenv('user_epoch_functions_folder') funcName '_params' filesep];
                case 'Dataset'
                    param_folder = [getenv('user_dataset_functions_folder') funcName '_params' filesep];
                case 'Cell'
                    param_folder = [getenv('user_cell_functions_folder') funcName '_params' filesep];
                case 'Mulit-cell'
                    param_folder = [getenv('user_multicell_functions_folder') funcName '_params' filesep];
            end            
            
            if exist(param_folder, 'dir')
                d = dir([param_folder,'*.mat']);
                L = length(d);
                paramSets = cell(1,L);
                for i=1:L
                    paramSets{i} = strtok(d(i).name,'.');
                end
                set(app.(param_menu_name), 'items', paramSets);
            else
                set(app.(param_menu_name), 'items', {});
            end     
            
            app.loadParamSet(event);
        end
        
        function loadParamSet(app, event)
            caller = event.Source.Tag;
            [~, caller_num] = strtok(caller, '_');
            caller_num_int = str2double(caller_num(2:end)); %remove leading _
            func_menu_name = ['FuncNameDropDown' caller_num];
            param_menu_name = ['ParamSetDropDown' caller_num];
            functype_menu_name = ['FuncTypeDropDown' caller_num];
           
            funcName = get(app.(func_menu_name), 'value');
            funcType = get(app.(functype_menu_name), 'value');
            paramSet_name = get(app.(param_menu_name), 'value');
            
            switch funcType
                case 'Epoch'
                    param_folder = [getenv('user_epoch_functions_folder') funcName '_params' filesep];
                case 'Dataset'
                    param_folder = [getenv('user_dataset_functions_folder') funcName '_params' filesep];
                case 'Cell'
                    param_folder = [getenv('user_cell_functions_folder') funcName '_params' filesep];
                case 'Mulit-cell'
                    param_folder = [getenv('user_multicell_functions_folder') funcName '_params' filesep];
            end            
            
            if exist(param_folder, 'dir')
                disp('loading');
                try
                    load([param_folder paramSet_name '.mat'], 'P'); %structure P stores the params
                    fprintf('Param set %s loaded.\n', paramSet_name);
                    app.analysisParams{caller_num_int} = P;
                catch ME
                    app.analysisParams{caller_num_int} = []; %set to empty
                    fprintf('Error loading param set %s\n', [param_folder paramSet_name '.mat']);
                    %rethrow(ME);
                end
            else
                app.analysisParams{caller_num_int} = []; %set to empty
                fprintf('No param folder folder %s\n', param_folder);
            end
        end
        
        function saveParamSet(app,event)
            caller = event.Source.Tag;
            [~, caller_num] = strtok(caller, '_');
            caller_num_int = str2double(caller_num(2:end)); %remove leading _
            func_menu_name = ['FuncNameDropDown' caller_num];
            param_menu_name = ['ParamSetDropDown' caller_num];
            functype_menu_name = ['FuncTypeDropDown' caller_num];
           
            funcName = get(app.(func_menu_name), 'value');
            funcType = get(app.(functype_menu_name), 'value');
            paramSet_name = get(app.(param_menu_name), 'value');
            
            switch funcType
                case 'Epoch'
                    param_folder = [getenv('user_epoch_functions_folder') funcName '_params' filesep];
                case 'Dataset'
                    param_folder = [getenv('user_dataset_functions_folder') funcName '_params' filesep];
                case 'Cell'
                    param_folder = [getenv('user_cell_functions_folder') funcName '_params' filesep];
                case 'Mulit-cell'
                    param_folder = [getenv('user_multicell_functions_folder') funcName '_params' filesep];
            end            
            
            if length(app.analysisParams) >= caller_num_int && ~isempty(app.analysisParams{caller_num_int})
                if ~exist(param_folder, 'dir')
                    fprintf('Making new parameter folder for %s\n', funcName);
                    mkdir(param_folder);
                end
                P = app.analysisParams{caller_num_int};
                com.mathworks.mwswing.MJFileChooserPerPlatform.setUseSwingDialog(1);
                %this fixed crash on replacing same param set file
                [fname, fpath] = uiputfile('*.mat','Save new parameter set as...',[param_folder paramSet_name]); 
                drawnow; pause(2); 
                %this fixed crash on replacing same param set file
                if fname
                    save([fpath fname], 'P');
                end
            end
            
            app.updateParamsMenu(event);
        end
        
        function editParamsTable(app, event)
            caller = event.Source.Tag;
            [~, caller_num] = strtok(caller, '_');
            caller_num_int = str2double(caller_num(2:end)); %remove leading _
            
            if length(app.analysisParams) >= caller_num_int && ...
                    ~isempty(app.analysisParams{caller_num_int})
                P = app.analysisParams{caller_num_int};
            else
                P = struct; 
            end
            
            T = table('Size',[10,2],'VariableTypes',{'string','string'});
            T.Properties.VariableNames = {'Parameter','Value'};
            
            if ~isempty(P)
                param_names = fieldnames(P);
                for i=1:length(param_names)
                    T{i,1} = {num2str(param_names{i})};
                    %test for vector
                    val = P.(param_names{i});
                    if iscell(val)
                        V = '{';
                        for n=1:length(val)
                            tempVal = val{n};
                            if ischar(tempVal)
                                V = [V '''' tempVal '''', ','];
                            elseif isnumeric(tempVal) && ~isscalar(tempVal)
                                V = [V mat2str(tempVal), ','];
                            else
                                V = [V num2str(tempVal) ','];
                            end
                        end
                        V = [V(1:end-1), '}'];
                        T{i,2} = {V};
                    elseif isnumeric(val) && ~isscalar(val)
                        T{i,2} = {mat2str(val)};
                    else
                        T{i,2} = {num2str(val)};
                    end
                end                
            end
                                                
            h = AppDataHandle;
            paramSetName = get(app.(['ParamSetDropDown' caller_num]), 'value');        
            paramTable_dlg = ParamTable_dlg(T,paramSetName,h);
            waitfor(paramTable_dlg);
            Tnew = h.data.table;
            
            Pnew = struct;
            for i=1:height(Tnew)
                if ~ismissing(Tnew{i,1}) && ~isempty(Tnew{i,1})
                    try
                        val = evalin("base", Tnew{i,2});
                    catch
                        val = Tnew{i,2};
                    end
                    Pnew.(Tnew{i,1}) = val;
                end
            end
            app.analysisParams{caller_num_int} = Pnew;
            
            
%             if isempty(param_names) %starting from scratch
%                 Ncols = 10; %hopefully this is enough parameters
%                 blank = true;
%             else
%                 Ncols = length(param_names);
%                 blank = false;
%             end
%             T = cell2table(cell(2,10));
%             T.Properties.RowNames = {'Parameter', 'Value'};
%             T.Properties.VariableNames = {};
%             
%             if ~blank
%                 for i=1:Ncols
%                     T{1,i} = {param_names{i}};
%                     T{2,i} = P.(param_names{i});
%                 end
%             end
%             
%             f = uifigure;
%             uitable(f,'Data',T,'ColumnEditable',true);
        end
        

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            D = table('Size',[4,2], ...
                'VariableNames',{'Param','Value'},...
                'VariableTypes',{'string','string'});
            set(app.ParamTable_1,'Data',D);
            
           %to load stuff for first analysis
           event.Source.Tag = 'FuncTypeDropDown_1';
           event.Value = 'Epoch';
           app.FuncTypeDropDown_1ValueChanged(event);
        end

        % Button pushed function: LoadsinglecellButton
        function LoadsinglecellButtonPushed(app, event)
            cell_id = get(app.cell_idEditField, 'value');
            app.searchTable = sl.SymphonyRecordedCell * sl.Dataset;
            app.loadedQuery = app.searchTable & sprintf('cell_id="%s"', cell_id);
            app.loadedQueryToDisplay = app.loadedQuery;
            app.updateLoadedListBoxes([]);
            app.checkMergeCompatibility();
        end

        % Button pushed function: LoadcellnamesButton
        function LoadcellnamesButtonPushed(app, event)
            [fname, fpath] = uigetfile('*.txt','Select plain text file with cell names.');
            if fname
                cell_ids = importdata([fpath, fname]);      
                app.searchTable = sl.SymphonyRecordedCell * sl.Dataset;
                app.loadedQuery = app.searchTable & struct('cell_id', cell_ids);
                app.loadedQueryToDisplay = app.loadedQuery;
                app.updateLoadedListBoxes([]);
                app.checkMergeCompatibility();
            end
        end

        % Value changed function: AnimalsListBox_L
        function AnimalsListBox_LValueChanged(app, event)
            value = app.AnimalsListBox_L.Value;
            app.loadedQueryToDisplay = app.loadedQuery & sprintf('animal_id=%s', value);
            app.updateLoadedListBoxes('animals');
        end

        % Value changed function: CellsListBox_L
        function CellsListBox_LValueChanged(app, event)
            value = app.CellsListBox_L.Value;
            app.loadedQueryToDisplay = app.loadedQuery & sprintf('cell_id="%s"', value);
            app.updateLoadedListBoxes('cells');
        end

        % Button pushed function: LoadqueryButton
        function LoadqueryButtonPushed(app, event)
            [fname, fpath] = uigetfile('.mat','Load query file',getenv('queryFolder'));
            if fname
                load([fpath fname], 'queryState');
                app.loadedQuery = parseQueryStruct(queryState);
                commandStr = 'app.searchTable=';
                currentTables = queryState.currentTables;
                N = length(currentTables);
                for i=1:N
                    commandStr = [commandStr, 'sl.' currentTables{i} '*'];
                end
                %remove trailing *
                commandStr = [commandStr(1:end-1) ,';'];
                eval(commandStr);
                app.loadedQueryToDisplay = app.loadedQuery;
                app.updateLoadedListBoxes([]);
                app.checkMergeCompatibility();
            end
        end

        % Button pushed function: QueryeditorButton
        function QueryeditorButtonPushed(app, event)
            h = AppDataHandle;
            qb_app = QueryBuilder(h);
            waitfor(qb_app);
            if isfield(h.data, 'queryResult')
                app.loadedQuery = h.data.queryResult;
                app.searchTable = h.data.searchTable;
                app.loadedQueryToDisplay = app.loadedQuery;
                app.updateLoadedListBoxes([]);
                app.checkMergeCompatibility();
            end
        end

        % Button pushed function: ClearLoadedButton
        function ClearLoadedButtonPushed(app, event)
            app.loadedQuery = [];
            app.loadedQueryToDisplay = app.loadedQuery;
            app.searchTable = [];
            app.updateLoadedListBoxes();
            app.checkMergeCompatibility();
        end

        % Button pushed function: FilterbyepochfunctionButton
        function FilterbyepochfunctionButtonPushed(app, event)
            fname = uigetfile('*.m', 'Select your filtering function', getenv('epochFilterFuncFolder'));
            if fname
                commandName = strtok(fname, '.'); 
                if ~isempty(app.loadedQuery)
                    %ep_query = sl.Epoch & app.loadedQuery.proj;
                    [~, ep_query] = getEpochCountInQuery(app, app.loadedQuery)
                    q_struct = ep_query.fetch('protocol_params');
                    fprintf('running filter function on %d epochs\n', length(q_struct));
                    tic;x
                    for i=1:length(q_struct) %check each epoch
                        epochForTest = q_struct(i);
                        epochForQuery = rmfield(q_struct(i), 'protocol_params');                        
                        passed = eval([commandName '(epochForTest);']);
                        thisTempEntry = sl.TempFilter & epochForQuery;                        
                        while thisTempEntry.exists
                            disp('waiting for another user working on this epoch')
                            pause(1);
                            thisTempEntry = sl.TempFilter & epochForTest;
                        end
                        epochForInsert = epochForQuery;
                        epochForInsert.passed = passed;
                        insert(sl.TempFilter,epochForInsert);                                                
                    end
                    fprintf('Time elapsed: %f seconds\n', toc);
                    %now run the filter by using restriction with temp table
                    passed_epochs = sl.TempFilter & 'passed=1';
                    fprintf('%d epochs passed.\n', passed_epochs.count);
                    %app.loadedQuery = app.loadedQuery & passed_epochs;
                    %drop empty datasets
                    datasets = sl.Dataset & proj(app.loadedQuery) & passed_epochs;
                    d_struct = fetch(datasets);
                    %d_struct_clean = rmfield(d_struct, 'passed');
                    d_struct_withEpochNums = fetch(datasets,'epoch_ids');
                    for i=1:length(d_struct)
%                         i
                        theseEpochNums = fetchn(passed_epochs & d_struct(i), 'epoch_number');
                        if isempty(intersect(d_struct_withEpochNums(i).epoch_ids, theseEpochNums))
                            app.loadedQuery = app.loadedQuery - d_struct(i);
                        end
%                         app.loadedQuery 
%                         pause;
                    end
                    app.loadedQueryToDisplay = app.loadedQuery;
                    app.updateLoadedListBoxes([]);
                    %and delete the temp filter entries
                    q_struct = ep_query.fetch();
                    delQuick(sl.TempFilter & q_struct);
                    disp('deleted TempFilter')
                end
            end
        end

        % Button pushed function: AlltoWGButton
        function AlltoWGButtonPushed(app, event)
            app.addToWorkingGroup(app.loadedQuery);
        end

        % Button pushed function: AnimaltoWGButton
        function AnimaltoWGButtonPushed(app, event)
            selected_animal = get(app.AnimalsListBox_L,'value');
            if ~isempty(selected_animal)
                app.addToWorkingGroup(app.loadedQuery & sprintf('animal_id=%s', selected_animal));
            end
        end

        % Button pushed function: CelltoWGButton
        function CelltoWGButtonPushed(app, event)
            selected_cell =  get(app.CellsListBox_L,'value');
            selected_animal = get(app.AnimalsListBox_L,'value');
            if ~isempty(selected_cell) && ~isempty(selected_animal)
                app.addToWorkingGroup(app.loadedQuery ...
                    & sprintf('cell_id="%s"', selected_cell) ...
                    & sprintf('animal_id=%s', selected_animal));
            end
        end

        % Button pushed function: DatasettoWGButton
        function DatasettoWGButtonPushed(app, event)
            selected_dataset = get(app.SymphonydatasetsListBox_L,'value');
            selected_cell =  get(app.CellsListBox_L,'value');
            selected_animal = get(app.AnimalsListBox_L,'value');
            if ~isempty(selected_dataset) && ~isempty(selected_cell) && ~isempty(selected_animal)
                app.addToWorkingGroup(app.loadedQuery ...
                    & sprintf('dataset_name="%s"', selected_dataset) ...
                    & sprintf('cell_id="%s"', selected_cell) ...
                    & sprintf('animal_id=%s', selected_animal))
            end
        end

        % Button pushed function: ClearWorkingGroupButton
        function ClearWorkingGroupButtonPushed(app, event)
            app.workingQuery = [];
            app.workingQueryToDisplay = app.workingQuery;
            app.updateWorkingListBoxes([]);
            app.checkMergeCompatibility();
        end

        % Value changed function: AnimalsListBox_W
        function AnimalsListBox_WValueChanged(app, event)
            value = app.AnimalsListBox_W.Value;
            app.workingQueryToDisplay = app.workingQuery & sprintf('animal_id=%s', value);
            app.updateWorkingListBoxes('animals');
        end

        % Value changed function: CellsListBox_W
        function CellsListBox_WValueChanged(app, event)
            value = app.CellsListBox_W.Value;
            app.workingQueryToDisplay = app.workingQuery & sprintf('cell_id="%s"', value);
            app.updateWorkingListBoxes('cells');
        end

        % Button pushed function: DitchanimalButton
        function DitchanimalButtonPushed(app, event)
            value = app.AnimalsListBox_W.Value;
            if ~isempty(value)
                app.workingQuery = app.workingQuery - sprintf('animal_id=%s', value);
                if app.workingQuery.count==0
                    app.ClearWorkingGroupButtonPushed(event);
                else
                    app.workingQueryToDisplay = app.workingQuery;
                    app.updateWorkingListBoxes([]);
                end
            end  
        end

        % Button pushed function: DitchcellButton
        function DitchcellButtonPushed(app, event)
            value = app.CellsListBox_W.Value;
            if ~isempty(value)
                app.workingQuery = app.workingQuery - sprintf('cell_id="%s"', value);
                if app.workingQuery.count==0
                    app.ClearWorkingGroupButtonPushed(event);
                else
                    app.workingQueryToDisplay = app.workingQuery;
                    app.updateWorkingListBoxes('cells_set');
                    app.AnimalsListBox_WValueChanged(event);
                end
            end
        end

        % Button pushed function: DitchdatasetButton
        function DitchdatasetButtonPushed(app, event)
            value = app.SymphonydatasetsListBox_W.Value;
            selected_cell =  get(app.CellsListBox_W,'value');
            selected_animal = get(app.AnimalsListBox_W,'value');
            if ~isempty(value) && ~isempty(selected_cell) && ~isempty(selected_animal)
                subtract_struct = struct('dataset_name', value, ...
                    'cell_id', selected_cell, ...
                    'animal_id', str2double(selected_animal));
                app.workingQuery = app.workingQuery - subtract_struct;
                if app.workingQuery.count==0
                    app.ClearWorkingGroupButtonPushed(event);
                else
                    app.workingQueryToDisplay = app.workingQuery;
                    app.updateWorkingListBoxes('datasets_set');
                    app.CellsListBox_WValueChanged(event);
                end
            end
        end

        % Button pushed function: SaveDataGroupButton
        function SaveDataGroupButtonPushed(app, event)
                [fname, fpath] = uiputfile('*.mat', 'Save DataGroup file...',getenv('workingGroupsFolder'));
                if fname
                    workingGroup.primaryKeyStruct = app.workingQuery.fetch();
                    workingGroup.searchTable = app.searchTable;    
                    save([fpath, fname], 'workingGroup', '-mat');
                end
        end

        % Button pushed function: LoadDataGroupButton
        function LoadDataGroupButtonPushed(app, event)
             [fname, fpath] = uigetfile('*.mat', 'Load DataGroup file...',getenv('workingGroupsFolder'));
                if fname
                    load([fpath, fname], 'workingGroup');
                    app.searchTable = workingGroup.searchTable;                    
                    app.loadedQuery = workingGroup.searchTable & workingGroup.primaryKeyStruct;
                    app.loadedQueryToDisplay = app.loadedQuery;
                    app.updateLoadedListBoxes([]);
                    app.checkMergeCompatibility();
                end
        end

        % Value changed function: FuncTypeDropDown_1
        function FuncTypeDropDown_1ValueChanged(app, event)
            app.updateFunctionsMenu(event);
        end

        % Button pushed function: testButton
        function testButtonPushed(app, event)
            app.runAnalysisFunction(event)
        end

        % Button pushed function: editButton
        function editButtonPushed(app, event)
            app.editParamsTable(event);
        end

        % Value changed function: FuncNameDropDown_1
        function FuncNameDropDown_1ValueChanged(app, event)
            app.updateParamsMenu(event);
        end

        % Value changed function: ParamSetDropDown_1
        function ParamSetDropDown_1ValueChanged(app, event)
            app.loadParamSet(event)
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            app.saveParamSet(event)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AnalysisPipelineBuilderUIFigure = uifigure('Visible', 'off');
            app.AnalysisPipelineBuilderUIFigure.Name = 'AnalysisPipelineBuilder';
            app.WorkingDataGroupPanel = uipanel(app.AnalysisPipelineBuilderUIFigure);
            app.WorkingDataGroupPanel.Title = 'Working DataGroup';
            app.CellsListBoxLabel = uilabel(app.WorkingDataGroupPanel);
            app.CellsListBoxLabel.Text = 'Cells';
            app.CellsListBox_W = uilistbox(app.WorkingDataGroupPanel);
            app.CellsListBox_W.Items = {};
            app.CellsListBox_W.ValueChangedFcn = createCallbackFcn(app, @CellsListBox_WValueChanged, true);
            app.CellsListBox_W.Value = {};
            app.SymphonydatasetsListBoxLabel = uilabel(app.WorkingDataGroupPanel);
            app.SymphonydatasetsListBoxLabel.Text = 'Symphony datasets';
            app.SymphonydatasetsListBox_W = uilistbox(app.WorkingDataGroupPanel);
            app.SymphonydatasetsListBox_W.Items = {};
            app.SymphonydatasetsListBox_W.Value = {};
            app.OtherdataListBoxLabel = uilabel(app.WorkingDataGroupPanel);
            app.OtherdataListBoxLabel.Text = 'Other data';
            app.OtherdataListBox_W = uilistbox(app.WorkingDataGroupPanel);
            app.OtherdataListBox_W.Items = {};
            app.OtherdataListBox_W.Value = {};
            app.AnimalsListBoxLabel = uilabel(app.WorkingDataGroupPanel);
            app.AnimalsListBoxLabel.Text = 'Animals';
            app.AnimalsListBox_W = uilistbox(app.WorkingDataGroupPanel);
            app.AnimalsListBox_W.Items = {};
            app.AnimalsListBox_W.ValueChangedFcn = createCallbackFcn(app, @AnimalsListBox_WValueChanged, true);
            app.AnimalsListBox_W.Value = {};
            app.TotalsPanel_2 = uipanel(app.WorkingDataGroupPanel);
            app.TotalsPanel_2.Title = 'Totals';
            app.animalsEditField_2Label = uilabel(app.TotalsPanel_2);
            app.animalsEditField_2Label.Text = 'animals';
            app.animalsEditField_W = uieditfield(app.TotalsPanel_2, 'text');
            app.animalsEditField_W.Editable = 'off';
            app.cellsEditField_3Label = uilabel(app.TotalsPanel_2);
            app.cellsEditField_3Label.Text = 'cells';
            app.cellsEditField_W = uieditfield(app.TotalsPanel_2, 'text');
            app.cellsEditField_W.Editable = 'off';
            app.epochsEditField_4Label = uilabel(app.TotalsPanel_2);
            app.epochsEditField_4Label.Text = 'epochs';
            app.epochsEditField_W = uieditfield(app.TotalsPanel_2, 'text');
            app.epochsEditField_W.Editable = 'off';
            app.eyesEditField_2Label = uilabel(app.TotalsPanel_2);
            app.eyesEditField_2Label.Text = 'eyes';
            app.eyesEditField_W = uieditfield(app.TotalsPanel_2, 'text');
            app.eyesEditField_W.Editable = 'off';
            app.datasetsEditField_4Label = uilabel(app.TotalsPanel_2);
            app.datasetsEditField_4Label.Text = 'datasets';
            app.datasetsEditField_W = uieditfield(app.TotalsPanel_2, 'text');
            app.datasetsEditField_W.Editable = 'off';
            app.otherfilesEditField_4Label = uilabel(app.TotalsPanel_2);
            app.otherfilesEditField_4Label.Text = 'other files';
            app.otherfilesEditField_W = uieditfield(app.TotalsPanel_2, 'text');
            app.otherfilesEditField_W.Editable = 'off';
            app.SaveDataGroupButton = uibutton(app.TotalsPanel_2, 'push');
            app.SaveDataGroupButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataGroupButtonPushed, true);
            app.SaveDataGroupButton.Text = 'Save DataGroup';
            app.ClearWorkingGroupButton = uibutton(app.TotalsPanel_2, 'push');
            app.ClearWorkingGroupButton.ButtonPushedFcn = createCallbackFcn(app, @ClearWorkingGroupButtonPushed, true);
            app.ClearWorkingGroupButton.Text = 'Clear';
            app.DitchanimalButton = uibutton(app.WorkingDataGroupPanel, 'push');
            app.DitchanimalButton.ButtonPushedFcn = createCallbackFcn(app, @DitchanimalButtonPushed, true);
            app.DitchanimalButton.Text = 'Ditch animal';
            app.DitchcellButton = uibutton(app.WorkingDataGroupPanel, 'push');
            app.DitchcellButton.ButtonPushedFcn = createCallbackFcn(app, @DitchcellButtonPushed, true);
            app.DitchcellButton.Text = 'Ditch cell';
            app.DitchdatasetButton = uibutton(app.WorkingDataGroupPanel, 'push');
            app.DitchdatasetButton.ButtonPushedFcn = createCallbackFcn(app, @DitchdatasetButtonPushed, true);
            app.DitchdatasetButton.Text = 'Ditch dataset';
            app.DitchotherdataButton = uibutton(app.WorkingDataGroupPanel, 'push');
            app.DitchotherdataButton.Text = 'Ditch other data';
            app.BuildanalysisPanel = uipanel(app.AnalysisPipelineBuilderUIFigure);
            app.BuildanalysisPanel.Title = 'Build analysis';
            app.Label = uilabel(app.BuildanalysisPanel);
            app.Label.Text = '1';
            app.FuncTypeDropDown_1 = uidropdown(app.BuildanalysisPanel);
            app.FuncTypeDropDown_1.Items = {'Epoch', 'Dataset', 'Cell', 'Multi-cell'};
            app.FuncTypeDropDown_1.ValueChangedFcn = createCallbackFcn(app, @FuncTypeDropDown_1ValueChanged, true);
            app.FuncTypeDropDown_1.Tag = 'FuncTypeDropDown_1';
            app.FuncTypeDropDown_1.Value = 'Epoch';
            app.FuncNameDropDown_1 = uidropdown(app.BuildanalysisPanel);
            app.FuncNameDropDown_1.ValueChangedFcn = createCallbackFcn(app, @FuncNameDropDown_1ValueChanged, true);
            app.FuncNameDropDown_1.Tag = 'FuncNameDownDown_1';
            app.FunctiontypeLabel = uilabel(app.BuildanalysisPanel);
            app.FunctiontypeLabel.Text = 'Function type';
            app.FunctionnameLabel = uilabel(app.BuildanalysisPanel);
            app.FunctionnameLabel.Text = 'Function name';
            app.StepsSpinnerLabel = uilabel(app.BuildanalysisPanel);
            app.StepsSpinnerLabel.Text = 'Steps';
            app.StepsSpinner = uispinner(app.BuildanalysisPanel);
            app.StepsSpinner.Limits = [0 10];
            app.StepsSpinner.Value = 1;
            app.testButton = uibutton(app.BuildanalysisPanel, 'push');
            app.testButton.ButtonPushedFcn = createCallbackFcn(app, @testButtonPushed, true);
            app.testButton.Tag = 'testinworkspaceButton_1';
            app.testButton.Text = 'test ';
            app.writetodatabaseButton_1 = uibutton(app.BuildanalysisPanel, 'push');
            app.writetodatabaseButton_1.Text = 'write to database';
            app.exporttoh5Button_1 = uibutton(app.BuildanalysisPanel, 'push');
            app.exporttoh5Button_1.Text = 'export to .h5';
            app.CheckBox = uicheckbox(app.BuildanalysisPanel);
            app.CheckBox.Text = '';
            app.saveButton = uibutton(app.BuildanalysisPanel, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Tag = 'saveparamsButton_1';
            app.saveButton.Text = 'save';
            app.editButton = uibutton(app.BuildanalysisPanel, 'push');
            app.editButton.ButtonPushedFcn = createCallbackFcn(app, @editButtonPushed, true);
            app.editButton.Tag = 'editparamsButton_1';
            app.editButton.Text = 'edit';
            app.ParametersetLabel = uilabel(app.BuildanalysisPanel);
            app.ParametersetLabel.Text = 'Parameter set';
            app.ParamSetDropDown_1 = uidropdown(app.BuildanalysisPanel);
            app.ParamSetDropDown_1.ValueChangedFcn = createCallbackFcn(app, @ParamSetDropDown_1ValueChanged, true);
            app.ParamSetDropDown_1.Tag = 'ParamSetDropDown_1';
            app.RunstepLabel = uilabel(app.BuildanalysisPanel);
            app.RunstepLabel.Text = 'Run step';
            app.overwriteLabel = uilabel(app.BuildanalysisPanel);
            app.overwriteLabel.Text = 'overwrite';
            app.PipelinePanel = uipanel(app.AnalysisPipelineBuilderUIFigure);
            app.PipelinePanel.Title = 'Pipeline';
            app.LoadpipelineButton = uibutton(app.PipelinePanel, 'push');
            app.LoadpipelineButton.Text = 'Load pipeline';
            app.SavepipelineButton = uibutton(app.PipelinePanel, 'push');
            app.SavepipelineButton.Text = 'Save pipeline';
            app.RunButton = uibutton(app.PipelinePanel, 'push');
            app.RunButton.Text = 'Run';
            app.h5exporttoolButton = uibutton(app.PipelinePanel, 'push');
            app.h5exporttoolButton.Text = '.h5 export tool';
            app.LoaddataPanel_2 = uipanel(app.AnalysisPipelineBuilderUIFigure);
            app.LoaddataPanel_2.Title = 'Load data';
            app.CellsListBox_2Label = uilabel(app.LoaddataPanel_2);
            app.CellsListBox_2Label.Text = 'Cells';
            app.CellsListBox_L = uilistbox(app.LoaddataPanel_2);
            app.CellsListBox_L.Items = {};
            app.CellsListBox_L.ValueChangedFcn = createCallbackFcn(app, @CellsListBox_LValueChanged, true);
            app.CellsListBox_L.Value = {};
            app.SymphonydatasetsListBox_2Label = uilabel(app.LoaddataPanel_2);
            app.SymphonydatasetsListBox_2Label.Text = 'Symphony datasets';
            app.SymphonydatasetsListBox_L = uilistbox(app.LoaddataPanel_2);
            app.SymphonydatasetsListBox_L.Items = {};
            app.SymphonydatasetsListBox_L.Value = {};
            app.OtherdataListBox_2Label = uilabel(app.LoaddataPanel_2);
            app.OtherdataListBox_2Label.Text = 'Other data';
            app.OtherdataListBox_L = uilistbox(app.LoaddataPanel_2);
            app.OtherdataListBox_L.Items = {};
            app.OtherdataListBox_L.Value = {};
            app.AnimalsListBox_2Label = uilabel(app.LoaddataPanel_2);
            app.AnimalsListBox_2Label.Text = 'Animals';
            app.AnimalsListBox_L = uilistbox(app.LoaddataPanel_2);
            app.AnimalsListBox_L.Items = {};
            app.AnimalsListBox_L.ValueChangedFcn = createCallbackFcn(app, @AnimalsListBox_LValueChanged, true);
            app.AnimalsListBox_L.Value = {};
            app.TotalsPanel_3 = uipanel(app.LoaddataPanel_2);
            app.TotalsPanel_3.Title = 'Totals';
            app.animalsEditField_2Label_2 = uilabel(app.TotalsPanel_3);
            app.animalsEditField_2Label_2.Text = 'animals';
            app.animalsEditField_L = uieditfield(app.TotalsPanel_3, 'text');
            app.animalsEditField_L.Editable = 'off';
            app.cellsEditField_3Label_2 = uilabel(app.TotalsPanel_3);
            app.cellsEditField_3Label_2.Text = 'cells';
            app.cellsEditField_L = uieditfield(app.TotalsPanel_3, 'text');
            app.cellsEditField_L.Editable = 'off';
            app.epochsEditField_4Label_2 = uilabel(app.TotalsPanel_3);
            app.epochsEditField_4Label_2.Text = 'epochs';
            app.epochsEditField_L = uieditfield(app.TotalsPanel_3, 'text');
            app.epochsEditField_L.Editable = 'off';
            app.eyesEditField_2Label_2 = uilabel(app.TotalsPanel_3);
            app.eyesEditField_2Label_2.Text = 'eyes';
            app.eyesEditField_L = uieditfield(app.TotalsPanel_3, 'text');
            app.eyesEditField_L.Editable = 'off';
            app.datasetsEditField_4Label_2 = uilabel(app.TotalsPanel_3);
            app.datasetsEditField_4Label_2.Text = 'datasets';
            app.datasetsEditField_L = uieditfield(app.TotalsPanel_3, 'text');
            app.datasetsEditField_L.Editable = 'off';
            app.otherfilesEditField_4Label_2 = uilabel(app.TotalsPanel_3);
            app.otherfilesEditField_4Label_2.Text = 'other files';
            app.otherfilesEditField_L = uieditfield(app.TotalsPanel_3, 'text');
            app.otherfilesEditField_L.Editable = 'off';
            app.AlltoWGButton = uibutton(app.TotalsPanel_3, 'push');
            app.AlltoWGButton.ButtonPushedFcn = createCallbackFcn(app, @AlltoWGButtonPushed, true);
            app.AlltoWGButton.Text = 'All to WG';
            app.ClearLoadedButton = uibutton(app.TotalsPanel_3, 'push');
            app.ClearLoadedButton.ButtonPushedFcn = createCallbackFcn(app, @ClearLoadedButtonPushed, true);
            app.ClearLoadedButton.Text = 'Clear';
            app.LoadqueryButton = uibutton(app.LoaddataPanel_2, 'push');
            app.LoadqueryButton.ButtonPushedFcn = createCallbackFcn(app, @LoadqueryButtonPushed, true);
            app.LoadqueryButton.Text = 'Load query';
            app.QueryeditorButton = uibutton(app.LoaddataPanel_2, 'push');
            app.QueryeditorButton.ButtonPushedFcn = createCallbackFcn(app, @QueryeditorButtonPushed, true);
            app.QueryeditorButton.Text = 'Query editor';
            app.LoadDataGroupButton = uibutton(app.LoaddataPanel_2, 'push');
            app.LoadDataGroupButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataGroupButtonPushed, true);
            app.LoadDataGroupButton.Text = 'Load DataGroup';
            app.LoadcellnamesButton = uibutton(app.LoaddataPanel_2, 'push');
            app.LoadcellnamesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadcellnamesButtonPushed, true);
            app.LoadcellnamesButton.Text = 'Load cell names';
            app.LoadsinglecellButton = uibutton(app.LoaddataPanel_2, 'push');
            app.LoadsinglecellButton.ButtonPushedFcn = createCallbackFcn(app, @LoadsinglecellButtonPushed, true);
            app.LoadsinglecellButton.Text = 'Load single cell';
            app.cell_idEditField = uieditfield(app.LoaddataPanel_2, 'text');
            app.AttachotherdataButton_2 = uibutton(app.LoaddataPanel_2, 'push');
            app.AttachotherdataButton_2.Text = 'Attach other data';
            app.FilterbyepochfunctionButton = uibutton(app.LoaddataPanel_2, 'push');
            app.FilterbyepochfunctionButton.ButtonPushedFcn = createCallbackFcn(app, @FilterbyepochfunctionButtonPushed, true);
            app.FilterbyepochfunctionButton.Text = 'Filter by epoch function';
            app.AnimaltoWGButton = uibutton(app.LoaddataPanel_2, 'push');
            app.AnimaltoWGButton.ButtonPushedFcn = createCallbackFcn(app, @AnimaltoWGButtonPushed, true);
            app.AnimaltoWGButton.Text = 'Animal to WG';
            app.CelltoWGButton = uibutton(app.LoaddataPanel_2, 'push');
            app.CelltoWGButton.ButtonPushedFcn = createCallbackFcn(app, @CelltoWGButtonPushed, true);
            app.CelltoWGButton.Text = 'Cell to WG';
            app.DatasettoWGButton = uibutton(app.LoaddataPanel_2, 'push');
            app.DatasettoWGButton.ButtonPushedFcn = createCallbackFcn(app, @DatasettoWGButtonPushed, true);
            app.DatasettoWGButton.Text = 'Dataset to WG';
            app.OtherfilestoWGButton = uibutton(app.LoaddataPanel_2, 'push');
            app.OtherfilestoWGButton.Text = 'Other files to WG';
            app.MergecompatibleLampLabel = uilabel(app.LoaddataPanel_2);
            app.MergecompatibleLampLabel.Text = 'Merge compatible';
            app.MergecompatibleLamp = uilamp(app.LoaddataPanel_2);
            % Show the figure after all components are created
            app.AnalysisPipelineBuilderUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AnalysisPipelineBuilder

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AnalysisPipelineBuilderUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AnalysisPipelineBuilderUIFigure)
        end
    end
end